classdef us_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ProjectDataSetupTab           matlab.ui.container.Tab
        PWVDirectoriesListBox         matlab.ui.control.ListBox
        MModeDirectoriesListBox       matlab.ui.control.ListBox
        SaveProjectFileButton         matlab.ui.control.Button
        LoadProjectFileButton         matlab.ui.control.Button
        ShortNameEditField            matlab.ui.control.EditField
        ShortNameEditFieldLabel       matlab.ui.control.Label
        AssociateandNameCurrentSelectionButton  matlab.ui.control.Button
        AddPWVDirectoryButton         matlab.ui.control.Button
        PWVDirectoriesLabel           matlab.ui.control.Label
        MModeDirectoriesListBoxLabel  matlab.ui.control.Label
        AddMModeDirectoryButton       matlab.ui.control.Button
        AnalysisTab                   matlab.ui.container.Tab
        PWVFrameIndexDropDown         matlab.ui.control.DropDown
        MmodeFrameIndexDropDown       matlab.ui.control.DropDown
        MiceListBox                   matlab.ui.control.ListBox
        ECGLowEditFieldPWV            matlab.ui.control.NumericEditField
        ECGLowEditField_2Label        matlab.ui.control.Label
        ECGTopEditFieldPWV            matlab.ui.control.NumericEditField
        ECGTopEditField_2Label        matlab.ui.control.Label
        ECGLowEditField               matlab.ui.control.NumericEditField
        ECGLowEditFieldLabel          matlab.ui.control.Label
        ECGTopEditField               matlab.ui.control.NumericEditField
        ECGTopEditFieldLabel          matlab.ui.control.Label
        SpeedRangeEditField           matlab.ui.control.EditField
        SpeedRangeEditFieldLabel      matlab.ui.control.Label
        GenerateCSVFileButton         matlab.ui.control.Button
        KeepMouseDataButton           matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        ChangerecordingPWV            matlab.ui.control.Button
        ComputeMeanCurveButtonPWV     matlab.ui.control.Button
        AddFrametoDataButtonPWV       matlab.ui.control.Button
        EstimateECGPeaksButtonPWV     matlab.ui.control.Button
        SegmentPWVCurveButton         matlab.ui.control.Button
        ViewsizeEditField             matlab.ui.control.NumericEditField
        ViewsizeEditFieldLabel        matlab.ui.control.Label
        FrameDropDown_2Label          matlab.ui.control.Label
        FrameDropDownLabel            matlab.ui.control.Label
        MiceListBoxLabel              matlab.ui.control.Label
        TasksPanel                    matlab.ui.container.Panel
        ChangerecordingMMode          matlab.ui.control.Button
        AddFrametoDataButton          matlab.ui.control.Button
        ComputeMeanCurveButton        matlab.ui.control.Button
        EstimateECGPeaksButton        matlab.ui.control.Button
        SegmentDiameterButton         matlab.ui.control.Button
        UIAxesPWVModeTmp2             matlab.ui.control.UIAxes
        UIAxesPWVModeTmp              matlab.ui.control.UIAxes
        UIAxesMModeTmp                matlab.ui.control.UIAxes
        UIAxesMMode                   matlab.ui.control.UIAxes
        UIAxesPWVMode                 matlab.ui.control.UIAxes
    end


    properties (Access = public)
        directoryPairs=cell(0) % Description
        currentIndex = 0 % Description
        mmode_frames=[];
        mmode_keep_frame=zeros(1,3);
        mmode_interp_frames={};
        pwv_frames=[];
        pwv_keep_frames=zeros(1,3);
        pwv_interp_frames=[];
        mmode_index=1;
        pwv_index=1;
        current_results=struct();
        doneStyle = uistyle(BackgroundColor="green");
        project_path=0;
        project_filename=0;
    end
    

    methods (Access = public)

        function displayImages(app,i)
            % Display mmode
            mmode_file=app.directoryPairs{i}{2};
            v=VideoReader(mmode_file);
            
            % 3 frames 
            v.CurrentTime=1-1/30;
            %  v.CurrentTime=1.3-2/30;
            tmp=readFrame(v);
            app.mmode_frames(1:size(tmp,1),:,:,1)=tmp;
            app.MmodeFrameIndexDropDown.Items = [app.MmodeFrameIndexDropDown.Items num2str(1)];
            app.current_results.mmode(1).use_data=0;

            if v.Duration >1 && v.Duration <2
                v.CurrentTime=1+1/30;
                app.mmode_frames(:,:,:,2)=readFrame(v);
                app.MmodeFrameIndexDropDown.Items = [app.MmodeFrameIndexDropDown.Items num2str(2)];
                app.current_results.mmode(2).use_data=0;
            end

            if v.Duration>=2
                % if v.Duration>=1+20/30
                v.CurrentTime=2;
                tmp=readFrame(v);
                app.mmode_frames(1:size(tmp,1),:,:,2)=tmp;
                app.MmodeFrameIndexDropDown.Items = [app.MmodeFrameIndexDropDown.Items num2str(2)];
                app.current_results.mmode(2).use_data=0;
            end

            if v.Duration>=3+1/30
                v.CurrentTime=3+1/30;
                tmp=readFrame(v);
                app.mmode_frames(1:size(tmp,1),:,:,3)=tmp;
                app.MmodeFrameIndexDropDown.Items = [app.MmodeFrameIndexDropDown.Items num2str(3)];
                app.current_results.mmode(3).use_data=0;
            end
            % select the region on the images from which we are going to detect the
            % artery diameter. The coordinates will be used later for ECG segmentation
            imagesc(app.UIAxesMMode,app.mmode_frames(:,:,2,1));
            app.UIAxesMMode.XLim = [-inf inf];
            app.UIAxesMMode.YLim = [-inf inf];


            % Display pwv
            pwv_file=app.directoryPairs{i}{3};
            pwv_v=VideoReader(pwv_file);
                        % 3 frames 
            v.CurrentTime=1-1/30;
            %  v.CurrentTime=1.3-2/30;
            tmp=readFrame(pwv_v);
            app.pwv_frames(1:size(tmp,1),:,:,1)=tmp;
            app.PWVFrameIndexDropDown.Items = [app.PWVFrameIndexDropDown.Items num2str(1)];
            app.current_results.pwv_mode(1).use_data=0;


            if pwv_v.Duration >1 && pwv_v.Duration <2
                pwv_v.CurrentTime=1+1/30;
                tmp=readFrame(pwv_v);
                app.pwv_frames(1:size(tmp,1),:,:,2)=tmp;
                app.PWVFrameIndexDropDown.Items = [app.PWVFrameIndexDropDown.Items num2str(2)];
                app.current_results.pwv_mode(2).use_data=0;
            end

            if pwv_v.Duration>=2
                % if v.Duration>=1+20/30
                pwv_v.CurrentTime=2;
                tmp=readFrame(pwv_v);
                app.pwv_frames(1:size(tmp,1),:,:,2)=tmp;
                app.PWVFrameIndexDropDown.Items = [app.PWVFrameIndexDropDown.Items num2str(2)];
                app.current_results.pwv_mode(2).use_data=0;
            end

            if pwv_v.Duration>=3+1/30
                pwv_v.CurrentTime=3+1/30;
                tmp=readFrame(pwv_v);
                app.pwv_frames(1:size(tmp,1),:,:,3)=tmp;
                app.PWVFrameIndexDropDown.Items = [app.PWVFrameIndexDropDown.Items num2str(3)];
                app.current_results.pwv_mode(3).use_data=0;
            end
            % select the region on the images from which we are going to detect the
            % artery diameter. The coordinates will be used later for ECG segmentation
            imagesc(app.UIAxesPWVMode,app.pwv_frames(:,:,2,1));
            app.UIAxesPWVMode.XLim = [-inf inf];
            app.UIAxesPWVMode.YLim = [-inf inf];

        end
        
        function change_display_index(app)
            imagesc(app.UIAxesMMode,app.mmode_frames(:,:,2,app.mmode_index));
            app.UIAxesMMode.XLim = [-inf inf];
            app.UIAxesMMode.YLim = [-inf inf];
            start_ECG=app.ECGLowEditField.Value;
            end_ECG=app.ECGTopEditField.Value;
            imsize=size(app.mmode_frames(:,:,2,app.mmode_index));
            line(app.UIAxesMMode,[1 imsize(2)],[start_ECG start_ECG],'Color','red');
            line(app.UIAxesMMode,[1 imsize(2)],[end_ECG end_ECG],'Color','red');

            imagesc(app.UIAxesPWVMode,app.pwv_frames(:,:,2,app.pwv_index));          
            app.UIAxesPWVMode.XLim = [-inf inf];
            app.UIAxesPWVMode.YLim = [-inf inf];
            start_ECG=app.ECGLowEditFieldPWV.Value;
            end_ECG=app.ECGTopEditFieldPWV.Value;
            imsize=size(app.pwv_frames(:,:,2,app.pwv_index));
            line(app.UIAxesPWVMode,[1 imsize(2)],[start_ECG start_ECG],'Color','red');
            line(app.UIAxesPWVMode,[1 imsize(2)],[end_ECG end_ECG],'Color','red')

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
            app.directoryPairs={};
        end

        % Button pushed function: AddMModeDirectoryButton
        function AddMModeDirectoryButtonPushed(app, event)
            [fileName,path]=uigetfile(fullfile('*.avi'),'Select M Mode video.');
            % if fileName ~ 0
            if exist("fileName", "var") %marleen
                app.MModeDirectoriesListBox.Items{end+1}=fullfile(path,fileName);
                app.MModeDirectoriesListBox.Value = fullfile(path,fileName);
            end
        end

        % Button pushed function: AddPWVDirectoryButton
        function AddPWVDirectoryButtonPushed(app, event)
            [fileName,path]=uigetfile(fullfile('*.avi'),'Select PWV Mode video.');
            % if fileName ~ 0
            if exist("fileName", "var") %marleen
                app.PWVDirectoriesListBox.Items{end+1}=fullfile(path,fileName);
                app.PWVDirectoriesListBox.Value = fullfile(path,fileName);
            end
        end

        % Button pushed function: SegmentDiameterButton
        function SegmentDiameterButtonPushed(app, event)
            %cropped=getframe(app.UIAxesMMode);
            xlimits = round(get(app.UIAxesMMode,'xlim'));
            ylimits = round(get(app.UIAxesMMode,'ylim'));
            cropped=squeeze(app.mmode_frames(ylimits(1):ylimits(2),xlimits(1):xlimits(2),2,app.mmode_index));

            int_factor = 10;
            app.mmode_interp_frames{app.mmode_index}=imgaussfilt(imresize(cropped(:,:),int_factor),int_factor);
            diameter=zeros(size(app.mmode_interp_frames{app.mmode_index},1),1);
            for j=1:size(app.mmode_interp_frames{app.mmode_index},2)
                [~,locs] = findpeaks(double(squeeze(app.mmode_interp_frames{app.mmode_index}(:,j))),'MinPeakDistance',size(app.mmode_interp_frames{app.mmode_index},1)/3,'SortStr','descend');
                if size(locs,1) <= 1 %marleen
                    msgbox(["Error in segmenting diameter"; "Please make sure the top and bottom of the vessel are in the frame."])
                    continue
                end
                diameter(j)=abs(locs(2)-locs(1));
            end
            
            pixelSize=app.ViewsizeEditField.Value/((583-388)*10);  %********388 and 583 are the pixel numbers that start and end the mmode image.
            diameter=diameter*pixelSize;

            plot(app.UIAxesMModeTmp,diameter);
            app.UIAxesMModeTmp.Title.String='Detected Diameter changes';
            % Save into structure
            app.current_results.mmode(app.mmode_index).diameter=diameter;
        end

        % Button pushed function: EstimateECGPeaksButton
        function EstimateECGPeaksButtonPushed(app, event)
            % Location of ECG bar in image
            start_ECG=app.ECGLowEditField.Value;
            end_ECG=app.ECGTopEditField.Value;
            %start_ECG=600;
            %end_ECG=670;
            xlimits = round(get(app.UIAxesMMode,'xlim'));
            frame=squeeze(app.mmode_frames(:,:,2,app.mmode_index));
            % Crop image to have a region only containing ECG
            framesECG=frame(start_ECG:end_ECG,xlimits(1):xlimits(2));
            imgI = imresize(framesECG,10);
            hold(app.UIAxesMModeTmp,'on')
            value=zeros(size(imgI,2),1);
            for i=1:size(imgI,2)
                [pks,locs] = findpeaks(flipud(imgI(:,i)),'NPeaks',1,'MinPeakHeight',40);  %before MinPeakDistance 60 max(ECG)*0.55
                value(i)=locs(1);
            end
            value=value-min(value);
            [pks,locs] = findpeaks(value,'MinPeakHeight',max(value)*0.9); 
            xline(app.UIAxesMModeTmp,locs);
            %plot(app.UIAxesMModeTmp,value)
            hold(app.UIAxesMModeTmp,'off')
            app.current_results.mmode(app.mmode_index).ecg_peaks=locs;
        end

        % Button pushed function: ComputeMeanCurveButton
        function ComputeMeanCurveButtonPushed(app, event)
             %% Average diameter based on consecutive cardiac cycles for each case considered
             min_dim=0;
             firsttime=0;
             for i=1:length(app.current_results.mmode)
                 if(app.current_results.mmode(i).use_data == 1)
                     cycleLen=app.current_results.mmode(i).ecg_peaks(2:end)-app.current_results.mmode(i).ecg_peaks(1:end-1);
                     cycleLength=min(cycleLen);
                     mean_diameter=zeros(cycleLength,1);
                     for j=1:length(cycleLen)
                         mean_diameter=mean_diameter+app.current_results.mmode(i).diameter(app.current_results.mmode(i).ecg_peaks(j):app.current_results.mmode(i).ecg_peaks(j)+cycleLength-1);
                     end
                     mean_diameter=mean_diameter/length(cycleLen);
                     %diameterPixelTime = 0.0011/10;
                     % diameterPixelTime = (60/min(ECGNum))/cycleLength;
                     app.current_results.mmode(i).mean_diameter=mean_diameter;
                     if (firsttime==0)
                         min_dim=length(app.current_results.mmode(i).mean_diameter);
                         firsttime=1;
                     else
                         if(length(app.current_results.mmode(i).mean_diameter)<min_dim)
                             min_dim=length(app.current_results.mmode(i).mean_diameter);
                         end
                     end

                 end
             end

            navg=0;
            mean_diameter=zeros(min_dim,1);
            for i=1:length(app.current_results.mmode)
                if(app.current_results.mmode(i).use_data)
                    mean_diameter=mean_diameter+app.current_results.mmode(i).mean_diameter(1:min_dim);
                    navg=navg+1;
                end
            end
            mean_diameter = mean_diameter/navg;
            final_result=app.directoryPairs{app.currentIndex}{4};
            final_result.mean_diameter=mean_diameter;
            app.directoryPairs{app.currentIndex}{4}=final_result;
            hold(app.UIAxesMModeTmp,'off')
            plot(app.UIAxesMModeTmp,mean_diameter);
        end

        % Button pushed function: AddFrametoDataButton
        function AddFrametoDataButtonPushed(app, event)
            app.current_results.mmode(app.mmode_index).use_data = 1;
            msgbox("Frame added"); %marleen
        end

        % Button pushed function: AssociateandNameCurrentSelectionButton
        function AssociateandNameCurrentSelectionButtonPushed(app, event)
            final_result=struct;
            value_mmode = app.MModeDirectoriesListBox.Value;
            value_pwv = app.PWVDirectoriesListBox.Value;
            name=app.ShortNameEditField.Value;
            app.MiceListBox.Items{end+1}=name;
            for i=1:size(app.directoryPairs,2)
                % Name already exist, ask for overide
                if(strcmp(name,app.directoryPairs{i}{1}))
                    answer = questdlg('Name already exist, overwrite?','Pair setup','Yes','No','No');
                    switch answer
                        case 'Yes'
                            app.directoryPairs{i}={name,value_mmode,value_pwv,final_result};
                        case 'No'
                            % break;
                            return %marleen
                    end
                else
                    app.directoryPairs{end+1}={name,value_mmode,value_pwv,final_result};
                end
            end
            if(size(app.directoryPairs,2)==0) 
                app.directoryPairs{end+1}={name,value_mmode,value_pwv,final_result};
            end
            msgbox("Mouse added"); %marleen
        end

        % Value changed function: MiceListBox
        function MiceListBoxValueChanged(app, event)
            name = app.MiceListBox.Value;
            % Find index we are working on
            for i=1:size(app.directoryPairs,2)
                % Name already exist, ask for override
                if(strcmp(name,app.directoryPairs{i}{1}))
                    break;
                end
            end
            app.currentIndex=i;
            app.MmodeFrameIndexDropDown.Items={};
            app.PWVFrameIndexDropDown.Items={};
            app.displayImages(i);
        end

        % Value changed function: MmodeFrameIndexDropDown
        function MmodeFrameIndexDropDownValueChanged(app, event)
            value = app.MmodeFrameIndexDropDown.Value;
            app.mmode_index=str2num(value);
            app.change_display_index();
            if(~isfield(app.current_results.mmode(app.mmode_index),'use_data'))
                app.current_results.mmode(app.mmode_index).use_data=0;
            end
        end

        % Value changed function: PWVFrameIndexDropDown
        function PWVFrameIndexDropDownValueChanged(app, event)
            value = app.PWVFrameIndexDropDown.Value;
            app.pwv_index=str2num(value);
            app.change_display_index()
        end

        % Value changed function: ViewsizeEditField
        function ViewsizeEditFieldValueChanged(app, event)
            value = app.ViewsizeEditField.Value;
            disp(value);
        end

        % Button pushed function: LoadProjectFileButton
        function LoadProjectFileButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [fileName,path]=uigetfile('*.mat','Select Project File.');
            delete(f)

            if(fileName~=0)
                load(fullfile(path,fileName));
                app.directoryPairs=directoryPairs;
                app.MModeDirectoriesListBox.Items={};
                app.PWVDirectoriesListBox.Items={};
                app.MiceListBox.Items={};
                for i=1:size(app.directoryPairs,2)
                    app.MModeDirectoriesListBox.Items{end+1}=directoryPairs{i}{2};
                    %app.MModeDirectoriesListBox.Value = directoryPairs{i}{2};
                    app.PWVDirectoriesListBox.Items{end+1}=directoryPairs{i}{3};
                    %app.PWVDirectoriesListBox.Value = directoryPairs{i}{3};
                    app.MiceListBox.Items{end+1}=directoryPairs{i}{1};
                    final_results=directoryPairs{i}{4};
                    if(isfield(final_results,'done') && final_results.done)
                        addStyle(app.MiceListBox,app.doneStyle,"item",i);
                    else
                        disp('x')
                    end

                end
                app.project_filename=fileName;
                app.project_path=path;
            end
        end

        % Button pushed function: SaveProjectFileButton
        function SaveProjectFileButtonPushed(app, event)
            [fileName,path]=uiputfile('project.mat');
            directoryPairs=app.directoryPairs;
            save(fullfile(path,fileName),'directoryPairs');
            app.project_filename=fileName;
            app.project_path=path;
        end

        % Button pushed function: EstimateECGPeaksButtonPWV
        function EstimateECGPeaksButtonPWVPushed(app, event)
            % Location of ECG bar in image
            start_ECG=app.ECGLowEditFieldPWV.Value;
            end_ECG=app.ECGTopEditFieldPWV.Value;
            %start_ECG=600;
            %end_ECG=671;
            xlimits = round(get(app.UIAxesPWVMode,'xlim'));
            frame=squeeze(app.pwv_frames(:,:,2,app.pwv_index));
            % Crop image to have a region only containing ECG
            framesECG=frame(start_ECG:end_ECG,xlimits(1):xlimits(2));
            imgI = imresize(framesECG,10);
            value=zeros(size(imgI,2),1);
            for i=1:size(imgI,2)
                [pks,locs] = findpeaks(flipud(imgI(:,i)),'NPeaks',1,'MinPeakHeight',40);  %before MinPeakDistance 60 max(ECG)*0.55
                value(i)=locs(1);
            end
            value=value-min(value);
            [pks,locs] = findpeaks(value,'MinPeakHeight',max(value)*0.9); 
            hold(app.UIAxesPWVModeTmp,'on')
            xline(app.UIAxesPWVModeTmp,locs);
            hold(app.UIAxesPWVModeTmp,'off')
            hold(app.UIAxesPWVModeTmp2,'on')
            xline(app.UIAxesPWVModeTmp2,locs);
            hold(app.UIAxesPWVModeTmp2,'off')

            app.current_results.pwv_mode(app.pwv_index).ecg_peaks=locs;
        end

        % Button pushed function: SegmentPWVCurveButton
        function SegmentPWVCurveButtonPushed(app, event)
            %cropped=getframe(app.UIAxesMMode);
            xlimits = round(get(app.UIAxesPWVMode,'xlim'));
            ylimits = round(get(app.UIAxesPWVMode,'ylim'));
            cropped=squeeze(app.pwv_frames(ylimits(1):ylimits(2),xlimits(1):xlimits(2),2,app.pwv_index));

            int_factor = 10;
            app.pwv_interp_frames{app.pwv_index}=imgaussfilt(imresize(cropped(:,:),int_factor),int_factor);
            vidFramecbin = bwmorph(app.pwv_interp_frames{app.pwv_index}>(mean(app.pwv_interp_frames{app.pwv_index})) & app.pwv_interp_frames{app.pwv_index}<max(app.pwv_interp_frames{app.pwv_index}),'close');
            imagesc(app.UIAxesPWVModeTmp,vidFramecbin)
            app.UIAxesPWVModeTmp.Title.String='Detected PWV enveloppe';
            centroidVelocity=zeros(size(vidFramecbin,2),1);
            for j=1:size(vidFramecbin,2)
                if isempty(find(vidFramecbin(:,j),1,'last'))
                    centroidVelocity(j)=1;
                else
                    % This corrects for the folding effect of bad PWV
                    centroidVelocity(j)=find(vidFramecbin(:,j),1,'last')-find(vidFramecbin(:,j),1,'first');
                end
            end

            pixelSize=str2num(app.SpeedRangeEditField.Value)/((594-400)*10);  %********400 and 594 are the pixel numbers that start and end the pwv image.
            centroidVelocity=centroidVelocity*pixelSize;

            plot(app.UIAxesPWVModeTmp2,centroidVelocity);
            % Save into structure
            app.current_results.pwv_mode(app.pwv_index).velocity=centroidVelocity; 
        end

        % Button pushed function: ComputeMeanCurveButtonPWV
        function ComputeMeanCurveButtonPWVPushed(app, event)
             %% Average velocity based on consecutive cardiac cycles for each case considered
             min_dim=0;
             firsttime=0;
             for i=1:length(app.current_results.pwv_mode)
                 if(app.current_results.pwv_mode(i).use_data == 1)
                     cycleLen=app.current_results.pwv_mode(i).ecg_peaks(2:end)-app.current_results.pwv_mode(i).ecg_peaks(1:end-1);
                     cycleLength=min(cycleLen);
                     mean_velocity=zeros(cycleLength,1);
                     for j=1:length(cycleLen)
                         mean_velocity=mean_velocity+app.current_results.pwv_mode(i).velocity(app.current_results.pwv_mode(i).ecg_peaks(j):app.current_results.pwv_mode(i).ecg_peaks(j)+cycleLength-1);
                     end
                     mean_velocity=mean_velocity/length(cycleLen);
                     %diameterPixelTime = 0.0011/10;
                     % diameterPixelTime = (60/min(ECGNum))/cycleLength;
                     app.current_results.pwv_mode(i).mean_velocity=mean_velocity;
                     if (firsttime==0)
                         min_dim=length(app.current_results.pwv_mode(i).mean_velocity);
                         firsttime=1;
                     else
                         if(length(app.current_results.pwv_mode(i).mean_velocity)<min_dim)
                             min_dim=length(app.current_results.pwv_mode(i).mean_velocity);
                         end
                     end
                 end
             end

            navg=0;
            mean_velocity=zeros(min_dim,1);
            for i=1:length(app.current_results.pwv_mode)
                if(app.current_results.pwv_mode(i).use_data)
                    mean_velocity=mean_velocity+app.current_results.pwv_mode(i).mean_velocity(1:min_dim);
                    navg=navg+1;
                end
            end
            mean_velocity = abs(mean_velocity/navg);
            final_result=app.directoryPairs{app.currentIndex}{4};
            final_result.mean_velocity=mean_velocity;
            app.directoryPairs{app.currentIndex}{4}=final_result;
            hold(app.UIAxesPWVModeTmp2,'off')
            plot(app.UIAxesPWVModeTmp2,mean_velocity);
            
        end

        % Button pushed function: AddFrametoDataButtonPWV
        function AddFrametoDataButtonPWVPushed(app, event)
            app.current_results.pwv_mode(app.pwv_index).use_data = 1;
            msgbox("Frame added"); %marleen
        end

        % Button pushed function: KeepMouseDataButton
        function KeepMouseDataButtonPushed(app, event)
            addStyle(app.MiceListBox,app.doneStyle,"item",app.currentIndex);
            final_result=app.directoryPairs{app.currentIndex}{4};
            final_result.done=1;
            app.directoryPairs{app.currentIndex}{4}=final_result;

            directoryPairs=app.directoryPairs;
            if(app.project_filename==0)
                [fileName,path]=uiputfile('project.mat','No project file defined yet, specify project location to be saved.');
                app.project_filename=fileName;
                app.project_path=path;
            end
            save(fullfile(app.project_path,app.project_filename),'directoryPairs');
            msgbox("Mouse saved in project file") %marleen
        end

        % Button pushed function: ChangerecordingMMode
        function ChangerecordingMModeButtonPushed(app, event)
            directoryPairs=app.directoryPairs;
            if(app.project_filename==0)
                [fileName,path]=uiputfile('project.mat','No project file defined yet, specify project location to be saved.');
                app.project_filename=fileName;
                app.project_path=path;
            end
            [fileName,path]=uigetfile('*.avi','Choose new MMode file.');
            if(fileName~=0)
                app.directoryPairs{app.currentIndex}{2}=fullfile(path,fileName);
                % Reset measures
                app.MmodeFrameIndexDropDown.Items={};
                app.PWVFrameIndexDropDown.Items={};
                app.displayImages(app.currentIndex);
            end
            save(fullfile(app.project_path,app.project_filename),'directoryPairs');
        end

        % Button pushed function: ChangerecordingPWV
        function ChangerecordingPWVButtonPushed(app, event)
            directoryPairs=app.directoryPairs;
            if(app.project_filename==0)
                [fileName,path]=uiputfile('project.mat','No project file defined yet, specify project location to be saved.');
                app.project_filename=fileName;
                app.project_path=path;
            end
            [fileName,path]=uigetfile('*.avi','Choose new PWV file.');
            if(fileName~=0)
                app.directoryPairs{app.currentIndex}{3}=fullfile(path,fileName);
                % Reset measures
                app.MmodeFrameIndexDropDown.Items={};
                app.PWVFrameIndexDropDown.Items={};
                app.displayImages(app.currentIndex);
            end
            save(fullfile(app.project_path,app.project_filename),'directoryPairs');
            
        end

        % Button pushed function: GenerateCSVFileButton
        function GenerateCSVFileButtonPushed(app, event)
            n_data=length(app.directoryPairs);
            names={};
            diameter_change=[];
            PI=[];
            RI=[];
            pct_diameter_change=[];
            mean_velocity=[];
            for i=1:n_data
                final_result=app.directoryPairs{i}{4};
                if(isfield(final_result,'done') && final_result.done)
                    names{end+1}=app.directoryPairs{i}{1};

                    if ~isfield(final_result,'mean_diameter') || size(final_result.mean_diameter, 1) < 1 %marleen: if you miss some data
                        answer = questdlg(['M Mode is not done for ' app.directoryPairs{i}{1} '. Do you want to save the file anyway?'], 'Saving problem', 'Yes, save as zero.', 'No, cancel.', 'No, cancel.'); %marleen
                        switch answer
                            case 'Yes, save as zero.'
                                diameter_change(end+1) = 0;
                                pct_diameter_change(end+1) = 0;
                            case 'No, cancel.'
                                return
                        end

                    else
                        diameter_change(end+1) = (max(final_result.mean_diameter)-min(final_result.mean_diameter));
                        pct_diameter_change(end+1)=(max(final_result.mean_diameter)-min(final_result.mean_diameter))/mean(final_result.mean_diameter);

                    end

                    if ~isfield(final_result, 'mean_velocity')|| size(final_result.mean_velocity, 1) < 1 %marleen: if you miss some data
                        answer = questdlg(['PW Mode is not done for ' app.directoryPairs{i}{1} '. Do you want to save the file anyway?'], 'Saving problem', 'Yes, save as zero.', 'No, cancel.', 'No, cancel.'); %marleen
                        switch answer
                            case 'Yes, save as zero.'
                                PI(end+1) = 0;
                                RI(end+1) = 0;
                                mean_velocity(end+1) = 0;
                            case 'No, cancel.'
                                return
                        end
                    else
                        %******compute the pulsatility index
                        PI(end+1) = (max(final_result.mean_velocity)-min(final_result.mean_velocity))/mean(final_result.mean_velocity);
                        %********calculate the resistance index
                        RI(end+1) = (max(final_result.mean_velocity)-min(final_result.mean_velocity))/max(final_result.mean_velocity);
                        mean_velocity(end+1)=mean(final_result.mean_velocity);
                    end

                end
            end

            T = table(diameter_change',PI',RI',pct_diameter_change',mean_velocity','RowNames',names');
            T.Properties.VariableNames = ["Diameter Change", "Pulsatility Index", "Resistence Index", "PCT Diameter Change", "Mean Velocity"]; %marleen
            % writetable(T,fullfile(app.project_path,'results.csv'),'WriteRowNames',true)
            answer = inputdlg("Saving name of results file"); %marleen
            writetable(T,fullfile(app.project_path,['results_' answer{1} '.csv']),'WriteRowNames',true); %marleen
        end

        % Value changed function: ECGTopEditField
        function ECGTopEditFieldValueChanged(app, event)
            value = app.ECGTopEditField.Value;
            change_display_index(app)
        end

        % Value changed function: ECGLowEditField
        function ECGLowEditFieldValueChanged(app, event)
            value = app.ECGLowEditField.Value;
            change_display_index(app)
        end

        % Value changed function: ECGTopEditFieldPWV
        function ECGTopEditFieldPWVValueChanged(app, event)
            value = app.ECGTopEditFieldPWV.Value;
            change_display_index(app)
        end

        % Value changed function: ECGLowEditFieldPWV
        function ECGLowEditFieldPWVValueChanged(app, event)
            value = app.ECGLowEditFieldPWV.Value;
            change_display_index(app)
        end

        % Clicked callback: MiceListBox
        function MiceListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProjectDataSetupTab = uitab(app.TabGroup);
            app.ProjectDataSetupTab.Title = 'Project Data Setup';
            app.AddMModeDirectoryButton = uibutton(app.ProjectDataSetupTab, 'push');
            app.AddMModeDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @AddMModeDirectoryButtonPushed, true);
            app.AddMModeDirectoryButton.Text = 'Add MMode Directory';
            app.MModeDirectoriesListBoxLabel = uilabel(app.ProjectDataSetupTab);
            app.MModeDirectoriesListBoxLabel.Text = 'MMode Directories';
            app.PWVDirectoriesLabel = uilabel(app.ProjectDataSetupTab);
            app.PWVDirectoriesLabel.Text = 'PWV Directories';
            app.AddPWVDirectoryButton = uibutton(app.ProjectDataSetupTab, 'push');
            app.AddPWVDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @AddPWVDirectoryButtonPushed, true);
            app.AddPWVDirectoryButton.Text = 'Add PWM Directory';
            app.AssociateandNameCurrentSelectionButton = uibutton(app.ProjectDataSetupTab, 'push');
            app.AssociateandNameCurrentSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AssociateandNameCurrentSelectionButtonPushed, true);
            app.AssociateandNameCurrentSelectionButton.Text = 'Associate and Name Current Selection';
            app.ShortNameEditFieldLabel = uilabel(app.ProjectDataSetupTab);
            app.ShortNameEditFieldLabel.Text = 'Short Name';
            app.ShortNameEditField = uieditfield(app.ProjectDataSetupTab, 'text');
            app.LoadProjectFileButton = uibutton(app.ProjectDataSetupTab, 'push');
            app.LoadProjectFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectFileButtonPushed, true);
            app.LoadProjectFileButton.Text = 'Load Project File';
            app.SaveProjectFileButton = uibutton(app.ProjectDataSetupTab, 'push');
            app.SaveProjectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProjectFileButtonPushed, true);
            app.SaveProjectFileButton.Text = 'Save Project File';
            app.MModeDirectoriesListBox = uilistbox(app.ProjectDataSetupTab);
            app.MModeDirectoriesListBox.Items = {};
            app.MModeDirectoriesListBox.Value = {};
            app.PWVDirectoriesListBox = uilistbox(app.ProjectDataSetupTab);
            app.PWVDirectoriesListBox.Items = {''};
            app.PWVDirectoriesListBox.Value = '';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.UIAxesPWVMode = uiaxes(app.AnalysisTab);
            title(app.UIAxesPWVMode, 'PWV Video')
            xlabel(app.UIAxesPWVMode, 'X')
            ylabel(app.UIAxesPWVMode, 'Y')
            zlabel(app.UIAxesPWVMode, 'Z')
            app.UIAxesMMode = uiaxes(app.AnalysisTab);
            title(app.UIAxesMMode, 'MMode Video')
            xlabel(app.UIAxesMMode, 'X')
            ylabel(app.UIAxesMMode, 'Y')
            zlabel(app.UIAxesMMode, 'Z')
            app.UIAxesMModeTmp = uiaxes(app.AnalysisTab);
            title(app.UIAxesMModeTmp, 'Intermediate data')
            xlabel(app.UIAxesMModeTmp, 'X')
            ylabel(app.UIAxesMModeTmp, 'Y')
            zlabel(app.UIAxesMModeTmp, 'Z')
            app.UIAxesPWVModeTmp = uiaxes(app.AnalysisTab);
            title(app.UIAxesPWVModeTmp, 'Intermediate data')
            xlabel(app.UIAxesPWVModeTmp, 'X')
            ylabel(app.UIAxesPWVModeTmp, 'Y')
            zlabel(app.UIAxesPWVModeTmp, 'Z')
            app.UIAxesPWVModeTmp2 = uiaxes(app.AnalysisTab);
            title(app.UIAxesPWVModeTmp2, 'Intermediate data')
            xlabel(app.UIAxesPWVModeTmp2, 'X')
            ylabel(app.UIAxesPWVModeTmp2, 'Y')
            zlabel(app.UIAxesPWVModeTmp2, 'Z')
            app.TasksPanel = uipanel(app.AnalysisTab);
            app.TasksPanel.Title = 'Tasks';
            app.SegmentDiameterButton = uibutton(app.TasksPanel, 'push');
            app.SegmentDiameterButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentDiameterButtonPushed, true);
            app.SegmentDiameterButton.Text = 'Segment Diameter';
            app.EstimateECGPeaksButton = uibutton(app.TasksPanel, 'push');
            app.EstimateECGPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateECGPeaksButtonPushed, true);
            app.EstimateECGPeaksButton.Text = 'Estimate ECG Peaks';
            app.ComputeMeanCurveButton = uibutton(app.TasksPanel, 'push');
            app.ComputeMeanCurveButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeMeanCurveButtonPushed, true);
            app.ComputeMeanCurveButton.Text = 'Compute Mean Curve';
            app.AddFrametoDataButton = uibutton(app.TasksPanel, 'push');
            app.AddFrametoDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddFrametoDataButtonPushed, true);
            app.AddFrametoDataButton.Text = 'Add Frame to Data';
            app.ChangerecordingMMode = uibutton(app.TasksPanel, 'push');
            app.ChangerecordingMMode.ButtonPushedFcn = createCallbackFcn(app, @ChangerecordingMModeButtonPushed, true);
            app.ChangerecordingMMode.Text = 'Change recording';
            app.MiceListBoxLabel = uilabel(app.AnalysisTab);
            app.MiceListBoxLabel.Text = 'Mice';
            app.FrameDropDownLabel = uilabel(app.AnalysisTab);
            app.FrameDropDownLabel.Text = 'Frame';
            app.FrameDropDown_2Label = uilabel(app.AnalysisTab);
            app.FrameDropDown_2Label.Text = 'Frame';
            app.ViewsizeEditFieldLabel = uilabel(app.AnalysisTab);
            app.ViewsizeEditFieldLabel.Text = 'View size';
            app.ViewsizeEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.ViewsizeEditField.ValueChangedFcn = createCallbackFcn(app, @ViewsizeEditFieldValueChanged, true);
            app.ViewsizeEditField.Value = 1.8;
            app.Panel = uipanel(app.AnalysisTab);
            app.Panel.Title = 'Panel';
            app.SegmentPWVCurveButton = uibutton(app.Panel, 'push');
            app.SegmentPWVCurveButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentPWVCurveButtonPushed, true);
            app.SegmentPWVCurveButton.Text = 'Segment PWV Curve';
            app.EstimateECGPeaksButtonPWV = uibutton(app.Panel, 'push');
            app.EstimateECGPeaksButtonPWV.ButtonPushedFcn = createCallbackFcn(app, @EstimateECGPeaksButtonPWVPushed, true);
            app.EstimateECGPeaksButtonPWV.Text = 'Estimate ECG Peaks';
            app.AddFrametoDataButtonPWV = uibutton(app.Panel, 'push');
            app.AddFrametoDataButtonPWV.ButtonPushedFcn = createCallbackFcn(app, @AddFrametoDataButtonPWVPushed, true);
            app.AddFrametoDataButtonPWV.Text = 'Add Frame to Data';
            app.ComputeMeanCurveButtonPWV = uibutton(app.Panel, 'push');
            app.ComputeMeanCurveButtonPWV.ButtonPushedFcn = createCallbackFcn(app, @ComputeMeanCurveButtonPWVPushed, true);
            app.ComputeMeanCurveButtonPWV.Text = 'Compute Mean Curve';
            app.ChangerecordingPWV = uibutton(app.Panel, 'push');
            app.ChangerecordingPWV.ButtonPushedFcn = createCallbackFcn(app, @ChangerecordingPWVButtonPushed, true);
            app.ChangerecordingPWV.Text = 'Change recording';
            app.KeepMouseDataButton = uibutton(app.AnalysisTab, 'push');
            app.KeepMouseDataButton.ButtonPushedFcn = createCallbackFcn(app, @KeepMouseDataButtonPushed, true);
            app.KeepMouseDataButton.Text = 'Keep Mouse Data';
            app.GenerateCSVFileButton = uibutton(app.AnalysisTab, 'push');
            app.GenerateCSVFileButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCSVFileButtonPushed, true);
            app.GenerateCSVFileButton.Text = 'Generate CSV File';
            app.SpeedRangeEditFieldLabel = uilabel(app.AnalysisTab);
            app.SpeedRangeEditFieldLabel.Text = 'Speed Range';
            app.SpeedRangeEditField = uieditfield(app.AnalysisTab, 'text');
            app.SpeedRangeEditField.Value = '560';
            app.ECGTopEditFieldLabel = uilabel(app.AnalysisTab);
            app.ECGTopEditFieldLabel.Text = 'ECG Top';
            app.ECGTopEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.ECGTopEditField.ValueChangedFcn = createCallbackFcn(app, @ECGTopEditFieldValueChanged, true);
            app.ECGTopEditField.Value = 680;
            app.ECGLowEditFieldLabel = uilabel(app.AnalysisTab);
            app.ECGLowEditFieldLabel.Text = 'ECG Low';
            app.ECGLowEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.ECGLowEditField.ValueChangedFcn = createCallbackFcn(app, @ECGLowEditFieldValueChanged, true);
            app.ECGLowEditField.Value = 640;
            app.ECGTopEditField_2Label = uilabel(app.AnalysisTab);
            app.ECGTopEditField_2Label.Text = 'ECG Top';
            app.ECGTopEditFieldPWV = uieditfield(app.AnalysisTab, 'numeric');
            app.ECGTopEditFieldPWV.ValueChangedFcn = createCallbackFcn(app, @ECGTopEditFieldPWVValueChanged, true);
            app.ECGTopEditFieldPWV.Value = 680;
            app.ECGLowEditField_2Label = uilabel(app.AnalysisTab);
            app.ECGLowEditField_2Label.Text = 'ECG Low';
            app.ECGLowEditFieldPWV = uieditfield(app.AnalysisTab, 'numeric');
            app.ECGLowEditFieldPWV.ValueChangedFcn = createCallbackFcn(app, @ECGLowEditFieldPWVValueChanged, true);
            app.ECGLowEditFieldPWV.Value = 650;
            app.MiceListBox = uilistbox(app.AnalysisTab);
            app.MiceListBox.Items = {};
            app.MiceListBox.ValueChangedFcn = createCallbackFcn(app, @MiceListBoxValueChanged, true);
            app.MiceListBox.ClickedFcn = createCallbackFcn(app, @MiceListBoxClicked, true);
            app.MiceListBox.Value = {};
            app.MmodeFrameIndexDropDown = uidropdown(app.AnalysisTab);
            app.MmodeFrameIndexDropDown.Items = {};
            app.MmodeFrameIndexDropDown.ValueChangedFcn = createCallbackFcn(app, @MmodeFrameIndexDropDownValueChanged, true);
            app.MmodeFrameIndexDropDown.Value = {};
            app.PWVFrameIndexDropDown = uidropdown(app.AnalysisTab);
            app.PWVFrameIndexDropDown.Items = {''};
            app.PWVFrameIndexDropDown.ValueChangedFcn = createCallbackFcn(app, @PWVFrameIndexDropDownValueChanged, true);
            app.PWVFrameIndexDropDown.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = us_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end